classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EmptyLabel                  matlab.ui.control.Label
        FullLabel                   matlab.ui.control.Label
        Shelf2CapacityGauge         matlab.ui.control.LinearGauge
        Shelf2CapacityGaugeLabel    matlab.ui.control.Label
        Shelf1CapacityGauge         matlab.ui.control.LinearGauge
        Shelf1CapacityGaugeLabel    matlab.ui.control.Label
        ESTOPButton                 matlab.ui.control.StateButton
        RetrieveItemButton          matlab.ui.control.Button
        RetrieveitemDropDown        matlab.ui.control.DropDown
        RetrieveitemDropDownLabel   matlab.ui.control.Label
        StoreItemButton             matlab.ui.control.Button
        ContainertypeDropDown       matlab.ui.control.DropDown
        ContainertypeDropDownLabel  matlab.ui.control.Label
        ItemnameEditField           matlab.ui.control.EditField
        ItemnameEditFieldLabel      matlab.ui.control.Label
        ChefsThirdArmLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
            controller;
            workspaceSize = [-1, 1, -1, 1, -0.012, 1];
            dobotBaseTransform = transl(0,0,0);
            simulationToggle = 1;
            realRobotToggle = 0;
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function workspaceInit(app)
            clf;
            workspace = Workspace(app.realRobotToggle, app.simulationToggle, app.dobotBaseTransform, app.workspaceSize);
            ROSCom = RosPublish(app.realRobotToggle);
            app.controller = Controller(workspace, ROSCom);
        end

        % Button pushed function: StoreItemButton
        function StoreButtonPressed(app, event)
            containerType = str2num(app.ContainertypeDropDown.Value);
            
            app.controller.StoreContainer(app.ItemnameEditField.Value, containerType);
            
            n = size(app.controller.workspace1.containerStorage, 2);
            for i=1:n
                app.RetrieveitemDropDown.Items(i) = {app.controller.workspace1.containerStorage(i).label};
            end
            
            %app.Shelf1CapacityGauge.Value = n1;
            %app.Shelf2CapacityGauge.Value = n2;
        end

        % Button pushed function: RetrieveItemButton
        function RetrieveButtonPressed(app, event)
            app.controller.RetrieveContainer(app.RetrieveitemDropDown.Value);
            
            [~,idx] = ismember(app.RetrieveitemDropDown.Value,app.RetrieveitemDropDown.Items);
            app.RetrieveitemDropDown.Items(idx) = [];
            
            %clear drop down to overwrite
            n = size(app.controller.workspace1.containerStorage, 2);
            for i=1:n
                app.RetrieveitemDropDown.Items(i) = {app.controller.workspace1.containerStorage(i).label};
            end
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChefsThirdArmLabel = uilabel(app.UIFigure);
            app.ChefsThirdArmLabel.Text = 'Chef''s Third Arm';
            app.ItemnameEditFieldLabel = uilabel(app.UIFigure);
            app.ItemnameEditFieldLabel.Text = 'Item name';
            app.ItemnameEditField = uieditfield(app.UIFigure, 'text');
            app.ContainertypeDropDownLabel = uilabel(app.UIFigure);
            app.ContainertypeDropDownLabel.Text = 'Container type';
            app.ContainertypeDropDown = uidropdown(app.UIFigure);
            app.ContainertypeDropDown.Items = {'1', '2', '3', '4'};
            app.ContainertypeDropDown.Value = '1';
            app.StoreItemButton = uibutton(app.UIFigure, 'push');
            app.StoreItemButton.ButtonPushedFcn = createCallbackFcn(app, @StoreButtonPressed, true);
            app.StoreItemButton.Text = 'Store Item';
            app.RetrieveitemDropDownLabel = uilabel(app.UIFigure);
            app.RetrieveitemDropDownLabel.Text = 'Retrieve item';
            app.RetrieveitemDropDown = uidropdown(app.UIFigure);
            app.RetrieveitemDropDown.Items = {};
            app.RetrieveitemDropDown.Value = {};
            app.RetrieveItemButton = uibutton(app.UIFigure, 'push');
            app.RetrieveItemButton.ButtonPushedFcn = createCallbackFcn(app, @RetrieveButtonPressed, true);
            app.RetrieveItemButton.Text = 'Retrieve Item';
            app.ESTOPButton = uibutton(app.UIFigure, 'state');
            app.ESTOPButton.Text = 'E-STOP';
            app.Shelf1CapacityGaugeLabel = uilabel(app.UIFigure);
            app.Shelf1CapacityGaugeLabel.Text = 'Shelf 1 Capacity';
            app.Shelf1CapacityGauge = uigauge(app.UIFigure, 'linear');
            app.Shelf1CapacityGauge.Limits = [0 6];
            app.Shelf1CapacityGauge.MajorTicks = [0 1 2 3 4 5 6];
            app.Shelf2CapacityGaugeLabel = uilabel(app.UIFigure);
            app.Shelf2CapacityGaugeLabel.Text = 'Shelf 2 Capacity';
            app.Shelf2CapacityGauge = uigauge(app.UIFigure, 'linear');
            app.Shelf2CapacityGauge.Limits = [0 6];
            app.Shelf2CapacityGauge.MajorTicks = [0 1 2 3 4 5 6];
            app.FullLabel = uilabel(app.UIFigure);
            app.FullLabel.Text = 'Full';
            app.EmptyLabel = uilabel(app.UIFigure);
            app.EmptyLabel.Text = 'Empty';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @workspaceInit)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end